classdef laba2app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        GridLayout          matlab.ui.container.GridLayout
        LeftPanel           matlab.ui.container.Panel
        AEditFieldLabel     matlab.ui.control.Label
        ui_A                matlab.ui.control.NumericEditField
        D0EditFieldLabel    matlab.ui.control.Label
        ui_D0               matlab.ui.control.NumericEditField
        tpEditFieldLabel    matlab.ui.control.Label
        ui_tp               matlab.ui.control.NumericEditField
        EditFieldLabel      matlab.ui.control.Label
        ui_num              matlab.ui.control.NumericEditField
        I0EditFieldLabel    matlab.ui.control.Label
        ui_I0               matlab.ui.control.NumericEditField
        faEditFieldLabel    matlab.ui.control.Label
        ui_fa               matlab.ui.control.NumericEditField
        Label_2             matlab.ui.control.Label
        ui_fa_min           matlab.ui.control.NumericEditField
        Label               matlab.ui.control.Label
        ui_fa_max           matlab.ui.control.NumericEditField
        Label_3             matlab.ui.control.Label
        Label_4             matlab.ui.control.Label
        faEditFieldLabel_2  matlab.ui.control.Label
        faEditFieldLabel_3  matlab.ui.control.Label
        GoButton            matlab.ui.control.Button
        progress            matlab.ui.control.Button
        Label_5             matlab.ui.control.Label
        ui_m                matlab.ui.control.NumericEditField
        clear               matlab.ui.control.Button
        ExcelCheckBox       matlab.ui.control.CheckBox
        RightPanel          matlab.ui.container.Panel
        UIAxes              matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        A % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, q)
            ui_AValueChanged(app);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {632, 632};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {276, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Value changed function: ui_A, ui_m
        function ui_AValueChanged(app, event)
            app.A = app.ui_A.Value * app.ui_m.Value;
            app.ui_I0.Value = app.A - 1;
            title(app.UIAxes,"I(f) при A="+app.A);
        end

        % Button pushed function: GoButton
        function GoButtonPushed(app, event)
            clearButtonPushed(app,event);
            I0 = app.ui_I0.Value;
            D0 = app.ui_D0.Value;
            tp = app.ui_tp.Value;
            ts = 0;
            famin = app.ui_fa.Value*app.ui_fa_min.Value/100;
            famax = app.ui_fa.Value*app.ui_fa_max.Value/100;
            num = app.ui_num.Value;
            
            c = 1;
            u = zeros(num+1, 3);
            
            wbar = permute(repmat(app.progress.BackgroundColor,15,1,200),[1,3,2]);
            wbar([1,end],:,:) = 0;
            wbar(:,[1,end],:) = 0;
            app.progress.Icon = wbar;
            
            for fa_curr = famin:(famax-famin)/num:famax
                [~, yy] = auxiliary(app.A, I0, D0, tp, ts, 10/fa_curr, fa_curr);
                yy((1:110), 1) = I0;
                u(c, 1) = fa_curr;
                u(c, 2) = min(yy(:,1));
                u(c, 3) = max(yy(:,1));
                c = c+1;
                currentProg = min(round((size(wbar,2)-2)*(c/num)),size(wbar,2)-2);
                app.progress.Icon(2:end-1, 2:currentProg+1, 1) = 0.25391;
                app.progress.Icon(2:end-1, 2:currentProg+1, 2) = 0.41016;
                app.progress.Icon(2:end-1, 2:currentProg+1, 3) = 0.87891;
                drawnow;
            end
            if (app.ExcelCheckBox.Value == true)
                writematrix(u,strcat(""+app.A,'.xlsx'),'Sheet',1); % сохранение в таблицу excel
            end
            plot(app.UIAxes,u(:,1), u(:,2), 'b-o', u(:,1), u(:,3), 'r-o'); % построение графиков
            legend(app.UIAxes,'Imin','Imax');
            
            app.progress.Icon = '';
            app.progress.Text = "Готово!";
            app.progress.BackgroundColor = [0.33,0.90,0.43];
        end

        % Callback function: clear, ui_D0, ui_I0
        function clearButtonPushed(app, event)
            app.progress.Text = "";
            app.progress.BackgroundColor = [0.96,0.96,0.96];
            cla(app.UIAxes);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.AEditFieldLabel = uilabel(app.LeftPanel);
            app.AEditFieldLabel.Text = 'A';
            app.ui_A = uieditfield(app.LeftPanel, 'numeric');
            app.ui_A.ValueChangedFcn = createCallbackFcn(app, @ui_AValueChanged, true);
            app.ui_A.Value = 3;
            app.D0EditFieldLabel = uilabel(app.LeftPanel);
            app.D0EditFieldLabel.Text = 'D0';
            app.ui_D0 = uieditfield(app.LeftPanel, 'numeric');
            app.ui_D0.ValueChangedFcn = createCallbackFcn(app, @clearButtonPushed, true);
            app.ui_D0.Value = 1;
            app.tpEditFieldLabel = uilabel(app.LeftPanel);
            app.tpEditFieldLabel.Text = 'tp';
            app.ui_tp = uieditfield(app.LeftPanel, 'numeric');
            app.ui_tp.Value = 60;
            app.EditFieldLabel = uilabel(app.LeftPanel);
            app.EditFieldLabel.Text = 'Кол-во точек';
            app.ui_num = uieditfield(app.LeftPanel, 'numeric');
            app.ui_num.Value = 50;
            app.I0EditFieldLabel = uilabel(app.LeftPanel);
            app.I0EditFieldLabel.Text = 'I0';
            app.ui_I0 = uieditfield(app.LeftPanel, 'numeric');
            app.ui_I0.ValueChangedFcn = createCallbackFcn(app, @clearButtonPushed, true);
            app.faEditFieldLabel = uilabel(app.LeftPanel);
            app.faEditFieldLabel.Text = 'fa';
            app.ui_fa = uieditfield(app.LeftPanel, 'numeric');
            app.ui_fa.Value = 0.053004;
            app.Label_2 = uilabel(app.LeftPanel);
            app.Label_2.Text = 'от';
            app.ui_fa_min = uieditfield(app.LeftPanel, 'numeric');
            app.ui_fa_min.Value = 5;
            app.Label = uilabel(app.LeftPanel);
            app.Label.Text = 'до';
            app.ui_fa_max = uieditfield(app.LeftPanel, 'numeric');
            app.ui_fa_max.Value = 125;
            app.Label_3 = uilabel(app.LeftPanel);
            app.Label_3.Text = '%';
            app.Label_4 = uilabel(app.LeftPanel);
            app.Label_4.Text = '%';
            app.faEditFieldLabel_2 = uilabel(app.LeftPanel);
            app.faEditFieldLabel_2.Text = 'МГц';
            app.faEditFieldLabel_3 = uilabel(app.LeftPanel);
            app.faEditFieldLabel_3.Text = 'пс';
            app.GoButton = uibutton(app.LeftPanel, 'push');
            app.GoButton.ButtonPushedFcn = createCallbackFcn(app, @GoButtonPushed, true);
            app.GoButton.Text = 'Go';
            app.progress = uibutton(app.LeftPanel, 'push');
            app.progress.Enable = 'off';
            app.progress.Text = '';
            app.Label_5 = uilabel(app.LeftPanel);
            app.Label_5.Text = '*';
            app.ui_m = uieditfield(app.LeftPanel, 'numeric');
            app.ui_m.ValueChangedFcn = createCallbackFcn(app, @ui_AValueChanged, true);
            app.ui_m.Value = 1;
            app.clear = uibutton(app.LeftPanel, 'push');
            app.clear.ButtonPushedFcn = createCallbackFcn(app, @clearButtonPushed, true);
            app.clear.Text = 'Очистить';
            app.ExcelCheckBox = uicheckbox(app.LeftPanel);
            app.ExcelCheckBox.Text = 'сохранить данные в Excel';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'f, tp^-1')
            ylabel(app.UIAxes, 'I(f)')
            zlabel(app.UIAxes, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = laba2app(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end